classdef mainSettings < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        figure1                 matlab.ui.Figure
        uipanel2_2              matlab.ui.container.ButtonGroup
        screenNum               matlab.ui.control.EditField
        text10                  matlab.ui.control.Label
        screens                 matlab.ui.control.Label
        chkGuv                  matlab.ui.control.CheckBox
        uipanel4                matlab.ui.container.Panel
        monitorHeight           matlab.ui.control.EditField
        flyDistance             matlab.ui.control.EditField
        text22                  matlab.ui.control.Label
        text21                  matlab.ui.control.Label
        text17                  matlab.ui.control.Label
        text14                  matlab.ui.control.Label
        text13                  matlab.ui.control.Label
        y1                      matlab.ui.control.EditField
        x1                      matlab.ui.control.EditField
        uipanel5                matlab.ui.container.Panel
        setPosButton            matlab.ui.control.Button
        showFlyPos              matlab.ui.control.Button
        uipanel3                matlab.ui.container.Panel
        saveParameters          matlab.ui.control.CheckBox
        change                  matlab.ui.control.Button
        pathName                matlab.ui.control.Label
        text6                   matlab.ui.control.Label
        cancel                  matlab.ui.control.Button
        ok                      matlab.ui.control.Button
        uipanel2                matlab.ui.container.ButtonGroup
        useScreen               matlab.ui.control.DropDown
        UsescreenDropDownLabel  matlab.ui.control.Label
        screenSize              matlab.ui.container.ButtonGroup
        FullScreenButton        matlab.ui.control.RadioButton
        PartialButton           matlab.ui.control.RadioButton
        widthPartial            matlab.ui.control.EditField
        heightPartial           matlab.ui.control.EditField
        gamma                   matlab.ui.control.EditField
        chkRotated              matlab.ui.control.CheckBox
        testInit                matlab.ui.control.Button
        text12                  matlab.ui.control.Label
        text9                   matlab.ui.control.Label
        text8                   matlab.ui.control.Label
        uipanel1                matlab.ui.container.Panel
        ypos                    matlab.ui.control.EditField
        xpos                    matlab.ui.control.EditField
        text5                   matlab.ui.control.Label
        text4                   matlab.ui.control.Label
        width                   matlab.ui.control.EditField
        height                  matlab.ui.control.EditField
        text3                   matlab.ui.control.Label
        text2                   matlab.ui.control.Label
        text1                   matlab.ui.control.Label
    end

    
    methods (Access = private)
        function checkResolution(app)
            % Checks resolution of monitor, if fullscreen or not, and
            % updates the resolution based on screen choice

            % Don't need to worry if only partial screen
            if app.PartialButton.Value == 1
                app.heightPartial.Enable = 'on';
                app.widthPartial.Enable  = 'on';
                return
            end

            app.heightPartial.Enable = 'off';
            app.widthPartial.Enable  = 'off';

            % Obtain screen resolution(s)
            [~, screenRes] = system("xdpyinfo | awk '/dimensions/{print $2}'");
            screenRes = splitlines(screenRes);
            % Get resolution relevant to the chosen screen
            screenRes = screenRes{str2double(app.screenNum.Value) + 1};
            screenRes = split(screenRes, 'x');
           
            % Switch width and height depending on user choice of rotating
            % screen
            switch app.chkRotated.Value
                case 0
                    app.widthPartial.Value = screenRes{1};
                    app.heightPartial.Value = screenRes{2};
                case 1
                    app.widthPartial.Value = screenRes{2};
                    app.heightPartial.Value = screenRes{1};
            end
        end
        
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function mainSettings_OpeningFcn(app, varargin)
            % --- Executes just before mainSettings is made visible.
            
            % Ensure that the app appears on screen when run
            movegui(app.figure1, 'onscreen');
            
            screenRes = get(0, 'ScreenSize'); %[1 1 screenWidth screenHeight]
            
            navData = getappdata(0, 'navData');
            
            set(gcf, 'Units' , 'Pixels');
            posdata = get(gcf,'Position');
            figHeight = posdata(4);
            figWidth = posdata(3);
            set(gcf, 'Position', [navData.windowPosition (screenRes(4)-figHeight)/2 figWidth figHeight]);

            %Get current settings
            screenData   = getappdata(0, 'screenData');
            navData   = getappdata(0, 'navData');

            triggerPos = screenData.triggerPos;
            
            savedWidth      = triggerPos(3)-triggerPos(1);
            savedHeight     = triggerPos(4)-triggerPos(2);
            savedXpos       = triggerPos(1);
            savedYpos       = triggerPos(2);
            
            savedScreenNum  = screenData.screenNumber;
            savedGamma      = screenData.gamma;
            
            savedWidthPartial   = screenData.partial(3);
            savedHeightPartial  = screenData.partial(4);
            
            % New variables
            useRotated    = screenData.useRotated;
            savedMonitorHeight = screenData.monitorHeight;
            savedFlyDistance   = screenData.flyDistance;
            
            selectedSaveParameters = navData.saveParameters;

            % OLOF ADDED
            savedX1=screenData.flyPos(1);
            savedY1=screenData.flyPos(2);
            
            %Set values
            app.width.Value = num2str(savedWidth);
            app.height.Value = num2str(savedHeight);
            app.xpos.Value = num2str(savedXpos);
            app.ypos.Value = num2str(savedYpos);
            app.screenNum.Value = num2str(savedScreenNum);
            app.gamma.Value = num2str(savedGamma);
            
            app.widthPartial.Value = num2str(savedWidthPartial);
            app.heightPartial.Value = num2str(savedHeightPartial);
            
            % OLOF ADDED
            app.x1.Value = num2str(savedX1);
            app.y1.Value = num2str(savedY1);
            
            %set current pathaname
            app.pathName.Text = navData.saveDataPathName;
            
            %write out available screens
            allScreens = Screen('Screens');
            screenArray = cell(1, length(allScreens));
            for i = 1:length(allScreens)
                screenArray{i} = num2str(allScreens(i));
            end
            app.useScreen.Items = screenArray;
            app.useScreen.Value = screenArray{1};
            
            if screenData.usePartial
                app.PartialButton.Value = 1;
            else
                app.FullScreenButton.Value = 1;
            end
            
            app.saveParameters.Value = selectedSaveParameters;

            app.chkGuv.Value = screenData.useGuvcview;
            app.chkRotated.Value = useRotated;
            app.monitorHeight.Value = num2str(savedMonitorHeight);
            app.flyDistance.Value = num2str(savedFlyDistance);

            % Force default values for screen resolution
            checkResolution(app);
        end

        % Button pushed function: cancel
        function cancel_Callback(app, event)
            % --- Executes on button press in cancel.
            close(gcf);
        end

        % Button pushed function: change
        function change_Callback(app, event)
            % --- Executes on button press in change.
            pathChoice = uigetdir(cd);
            if pathChoice ~= 0 %cancel returns 0
                app.pathName.Text = pathChoice;
            end
        end

        % Button pushed function: ok
        function ok_Callback(app, event)
            % --- Executes on button press in ok.
            navData    = getappdata(0, 'navData');
            screenData = getappdata(0, 'screenData');
            
            %get values
            widthChoice  = str2double(app.width.Value);
            heightChoice = str2double(app.height.Value);
            
            xposChoice = str2double(app.xpos.Value);
            yposChoice = str2double(app.ypos.Value);
            
            screenNumChoice     = round(str2double(app.screenNum.Value));
            gammaChoice         = str2double(app.gamma.Value);
            widthPartialChoice  = str2double(app.widthPartial.Value);
            heightPartialChoice = str2double(app.heightPartial.Value);
            
            x1Choice = str2double(app.x1.Value);
            y1Choice = str2double(app.y1.Value);
            
            
            pathNameChoice = app.pathName.Text;
            
            choice = 'Yes';
            if screenNumChoice > max(Screen('Screens')) || screenNumChoice < min(Screen('Screens'))
                choice = questdlg('Warning: The chosen screen number does not seem to exist. Continue?', 'Yes', 'No');
            end
            
            if strcmp(choice, 'Yes')
            
                %set values
                screenData.triggerPos    = [xposChoice yposChoice xposChoice + widthChoice yposChoice + heightChoice];
                screenData.partial       = [0 0 widthPartialChoice heightPartialChoice];
                screenData.screenNumber  = screenNumChoice;
                screenData.gamma         = gammaChoice;
                % New variables
                screenData.useGuvcview   = app.chkGuv.Value;
                screenData.useRotated    = app.chkRotated.Value;
                screenData.flyDistance   = str2double(app.flyDistance.Value);
                screenData.monitorHeight = str2double(app.monitorHeight.Value);
                screenData.flyPos = [x1Choice y1Choice];
            
            
                navData.saveDataPathName = pathNameChoice;
            
                %Partial Screen
                if app.PartialButton.Value
                    screenData.usePartial = true;
                else
                    screenData.usePartial = false;
                end
            
                %Disable data
                if app.saveParameters.Value
                    navData.saveParameters = true;
                else
                    navData.saveParameters = false;
                end
            
                setappdata(0, 'navData',    navData);
                setappdata(0, 'screenData', screenData);
            
                close(gcf); %Close window
            end
        end

        % Button pushed function: setPosButton
        function setPosButton_Callback(app, event)
            % --- Executes on button press in setFlyPos.
            screenData = getappdata(0, 'screenData');
            
            if screenData.isInit
                [xPos, yPos] = setFlyPos(screenData);
                screenData.flyPos = [xPos yPos];
            
                setappdata(0, 'screenData', screenData);
            
                app.x1.Value = num2str(xPos);
                app.y1.Value = num2str(yPos);
            else
                disp('Screen not initialized');
            end
        end

        % Button pushed function: showFlyPos
        function showFlyPos_Callback(app, event)
            % --- Executes on button press in showFlyPos.
            screenData = getappdata(0, 'screenData');

            screenData.flyPos = [str2double(app.x1.Value) str2double(app.y1.Value)];

            setappdata(0, 'screenData', screenData);
            
            if screenData.isInit
                screenFcn('ShowFlyPos');
            else
                disp('Screen not initialized');
            end
        end

        % Button pushed function: testInit
        function testInit_Callback(app, event)
            % --- Executes on button press in testInit.
            disp(' ');
            disp('This function will perform a test of the screen flip interval and then automatically close the window.');
            disp('Use to make sure you don`t cover GUI by accident.');
            
            screenNumber  = str2double(app.screenNum.Value);
            
            h = str2double(app.heightPartial.Value);
            w = str2double(app.widthPartial.Value);
            
            a = 0;
            b = 0;
            screenPartial = [a b a+w b+h];
            
            disp('Open')
            
            if app.screenSize.SelectedObject == "Partial"
                [wPtr,~] = Screen('OpenWindow', screenNumber, [255 255 255], screenPartial);
            else
                [wPtr,~] = Screen('OpenWindow', screenNumber, [255 255 255]); %fullscreen
            end
            
            [monitorFlipInterval, nrValidSamples, stddev] = Screen('GetFlipInterval', wPtr, 25);
            Screen('Close', wPtr);
            
            fprintf('Over %d samples the mean flip interval was %.2f ms (%.2f Hz), stddev=%.2f us', ...
                nrValidSamples, monitorFlipInterval*1000, 1/monitorFlipInterval, stddev*1000000);
            disp('Close')
        end

        % Value changed function: chkRotated
        function chkRotatedValueChanged(app, event)
            % When rotating screen, swap relevant height and width variables
            swapSpace = app.heightPartial.Value;
            app.heightPartial.Value = app.widthPartial.Value;
            app.widthPartial.Value = swapSpace;

            swapSpace = app.y1.Value;
            app.y1.Value = app.x1.Value;
            app.x1.Value = swapSpace;
        end

        % Selection changed function: screenSize
        function screenSizeSelectionChanged(app, event)
            checkResolution(app);
        end

        % Value changed function: screenNum
        function screenNumValueChanged(app, event)
            checkResolution(app);            
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.figure1 = uifigure('Visible', 'off');
            colormap(app.figure1, 'parula');
            app.figure1.Name = 'mainSettings';
            app.figure1.Tag = 'figure1';
            app.text1 = uilabel(app.figure1);
            app.text1.Tag = 'text1';
            app.text1.Text = 'Settings';
            app.uipanel1 = uipanel(app.figure1);
            app.uipanel1.Title = 'Trigger';
            app.uipanel1.Tag = 'uipanel1';
            app.text2 = uilabel(app.uipanel1);
            app.text2.Tag = 'text2';
            app.text2.Text = 'Height [px]';
            app.text3 = uilabel(app.uipanel1);
            app.text3.Tag = 'text3';
            app.text3.Text = 'Width [px]';
            app.height = uieditfield(app.uipanel1, 'text');
            app.height.Tag = 'height';
            app.height.Value = '50';
            app.width = uieditfield(app.uipanel1, 'text');
            app.width.Tag = 'width';
            app.width.Value = '50';
            app.text4 = uilabel(app.uipanel1);
            app.text4.Tag = 'text4';
            app.text4.Text = 'Xpos [px]';
            app.text5 = uilabel(app.uipanel1);
            app.text5.Tag = 'text5';
            app.text5.Text = 'Ypos [px]';
            app.xpos = uieditfield(app.uipanel1, 'text');
            app.xpos.Tag = 'xpos';
            app.xpos.Value = '50';
            app.ypos = uieditfield(app.uipanel1, 'text');
            app.ypos.Tag = 'ypos';
            app.ypos.Value = '50';
            app.uipanel2 = uibuttongroup(app.figure1);
            app.uipanel2.Title = 'Screen';
            app.uipanel2.Tag = 'uipanel2';
            app.text8 = uilabel(app.uipanel2);
            app.text8.Tag = 'text8';
            app.text8.Text = 'Width [px]';
            app.text9 = uilabel(app.uipanel2);
            app.text9.Tag = 'text9';
            app.text9.Text = 'Height [px]';
            app.text12 = uilabel(app.uipanel2);
            app.text12.Tag = 'text12';
            app.text12.Text = 'Gamma';
            app.testInit = uibutton(app.uipanel2, 'push');
            app.testInit.ButtonPushedFcn = createCallbackFcn(app, @testInit_Callback, true);
            app.testInit.Tag = 'testInit';
            app.testInit.Text = '-Test Init-';
            app.chkRotated = uicheckbox(app.uipanel2);
            app.chkRotated.ValueChangedFcn = createCallbackFcn(app, @chkRotatedValueChanged, true);
            app.chkRotated.Tag = 'chkRotated';
            app.chkRotated.Text = 'Use 90 degree rotation';
            app.gamma = uieditfield(app.uipanel2, 'text');
            app.gamma.Tag = 'gamma';
            app.gamma.Value = '1.6';
            app.heightPartial = uieditfield(app.uipanel2, 'text');
            app.heightPartial.Tag = 'heightPartial';
            app.heightPartial.Value = '50';
            app.widthPartial = uieditfield(app.uipanel2, 'text');
            app.widthPartial.Tag = 'widthPartial';
            app.widthPartial.Value = '50';
            app.screenSize = uibuttongroup(app.uipanel2);
            app.screenSize.SelectionChangedFcn = createCallbackFcn(app, @screenSizeSelectionChanged, true);
            app.PartialButton = uiradiobutton(app.screenSize);
            app.PartialButton.Text = 'Partial';
            app.FullScreenButton = uiradiobutton(app.screenSize);
            app.FullScreenButton.Text = 'Full Screen';
            app.FullScreenButton.Value = true;
            app.UsescreenDropDownLabel = uilabel(app.uipanel2);
            app.UsescreenDropDownLabel.Text = 'Use screen:';
            app.useScreen = uidropdown(app.uipanel2);
            app.ok = uibutton(app.figure1, 'push');
            app.ok.ButtonPushedFcn = createCallbackFcn(app, @ok_Callback, true);
            app.ok.Tag = 'ok';
            app.ok.Text = 'OK';
            app.cancel = uibutton(app.figure1, 'push');
            app.cancel.ButtonPushedFcn = createCallbackFcn(app, @cancel_Callback, true);
            app.cancel.Tag = 'cancel';
            app.cancel.Text = 'Cancel';
            app.uipanel3 = uipanel(app.figure1);
            app.uipanel3.Title = 'Data Saving';
            app.uipanel3.Tag = 'uipanel3';
            app.text6 = uilabel(app.uipanel3);
            app.text6.Tag = 'text6';
            app.text6.Text = 'Data folder:';
            app.pathName = uilabel(app.uipanel3);
            app.pathName.Tag = 'pathName';
            app.pathName.Text = 'Static Text';
            app.change = uibutton(app.uipanel3, 'push');
            app.change.ButtonPushedFcn = createCallbackFcn(app, @change_Callback, true);
            app.change.Tag = 'change';
            app.change.Text = 'Change';
            app.saveParameters = uicheckbox(app.uipanel3);
            app.saveParameters.Tag = 'saveParameters';
            app.saveParameters.Text = 'Enable Saving Data';
            app.uipanel4 = uipanel(app.figure1);
            app.uipanel4.Title = 'Fly position';
            app.uipanel4.Tag = 'uipanel4';
            app.uipanel5 = uipanel(app.uipanel4);
            app.uipanel5.Title = 'On-screen ';
            app.uipanel5.Tag = 'uipanel5';
            app.showFlyPos = uibutton(app.uipanel5, 'push');
            app.showFlyPos.ButtonPushedFcn = createCallbackFcn(app, @showFlyPos_Callback, true);
            app.showFlyPos.Tag = 'showFlyPos';
            app.showFlyPos.Text = 'Show fly pos';
            app.setPosButton = uibutton(app.uipanel5, 'push');
            app.setPosButton.ButtonPushedFcn = createCallbackFcn(app, @setPosButton_Callback, true);
            app.setPosButton.Tag = 'setFlyPos';
            app.setPosButton.Text = 'Set fly pos';
            app.x1 = uieditfield(app.uipanel4, 'text');
            app.x1.Tag = 'x1';
            app.x1.Value = '320';
            app.y1 = uieditfield(app.uipanel4, 'text');
            app.y1.Tag = 'y1';
            app.y1.Value = '240';
            app.text13 = uilabel(app.uipanel4);
            app.text13.Tag = 'text13';
            app.text13.Text = 'x:';
            app.text14 = uilabel(app.uipanel4);
            app.text14.Tag = 'text14';
            app.text14.Text = 'y:';
            app.text17 = uilabel(app.uipanel4);
            app.text17.Tag = 'text17';
            app.text17.Text = 'Position';
            app.text21 = uilabel(app.uipanel4);
            app.text21.Tag = 'text21';
            app.text21.Text = 'Monitor height [cm]:';
            app.text22 = uilabel(app.uipanel4);
            app.text22.Tag = 'text22';
            app.text22.Text = 'Distance from monitor [cm]:';
            app.flyDistance = uieditfield(app.uipanel4, 'text');
            app.flyDistance.Tag = 'flyDistance';
            app.flyDistance.Value = '14';
            app.monitorHeight = uieditfield(app.uipanel4, 'text');
            app.monitorHeight.Tag = 'monitorHeight';
            app.monitorHeight.Value = '27.5';
            app.uipanel2_2 = uibuttongroup(app.figure1);
            app.uipanel2_2.Title = 'Video Settings';
            app.uipanel2_2.Tag = 'uipanel2';
            app.chkGuv = uicheckbox(app.uipanel2_2);
            app.chkGuv.Tag = 'chkVidCapture';
            app.chkGuv.Text = 'Use guvcview to record';
            app.chkGuv.Value = true;
            app.screens = uilabel(app.uipanel2_2);
            app.screens.Tag = 'screens';
            app.screens.Text = 'Static Text';
            app.text10 = uilabel(app.uipanel2_2);
            app.text10.Tag = 'text10';
            app.text10.Text = 'Number';
            app.screenNum = uieditfield(app.uipanel2_2, 'text');
            app.screenNum.ValueChangedFcn = createCallbackFcn(app, @screenNumValueChanged, true);
            app.screenNum.Tag = 'screenNum';
            app.screenNum.Value = '0';
            % Show the figure after all components are created
            app.figure1.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = mainSettings(varargin)

            runningApp = getRunningApp(app);

            % Check for running singleton app
            if isempty(runningApp)

                % Create UIFigure and components
                createComponents(app)

                % Register the app with App Designer
                registerApp(app, app.figure1)

                % Execute the startup function
                runStartupFcn(app, @(app)mainSettings_OpeningFcn(app, varargin{:}))
            else

                % Focus the running singleton app
                figure(runningApp.figure1)

                app = runningApp;
            end

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.figure1)
        end
    end
end