classdef tableGui < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        figure1                       matlab.ui.Figure
        file                          matlab.ui.container.Menu
        new                           matlab.ui.container.Menu
        openFile                      matlab.ui.container.Menu
        saveAs                        matlab.ui.container.Menu
        importStim                    matlab.ui.container.Menu
        exit                          matlab.ui.container.Menu
        tools                         matlab.ui.container.Menu
        recordStimulus                matlab.ui.container.Menu
        avimovie                      matlab.ui.container.Menu
        imsequence                    matlab.ui.container.Menu
        settings                      matlab.ui.container.Menu
        TabGroup                      matlab.ui.container.TabGroup
        StandardtoolsTab              matlab.ui.container.Tab
        PreviewVideoPanel             matlab.ui.container.Panel
        previewVid                    matlab.ui.control.Button
        uipanel2                      matlab.ui.container.Panel
        chosenRow                     matlab.ui.control.EditField
        rowLog                        matlab.ui.control.Button
        rowShuffle_1                  matlab.ui.control.Button
        useValue1                     matlab.ui.control.Button
        chosenCol                     matlab.ui.control.EditField
        valNum                        matlab.ui.control.EditField
        shiftColLeft                  matlab.ui.control.Button
        shiftColRight                 matlab.ui.control.Button
        rowLin                        matlab.ui.control.Button
        text10                        matlab.ui.control.Label
        allRows                       matlab.ui.control.CheckBox
        uipanel1                      matlab.ui.container.Panel
        text5                         matlab.ui.control.Label
        numRuns                       matlab.ui.control.EditField
        numRunsOk                     matlab.ui.control.Button
        trialSubset                   matlab.ui.control.CheckBox
        trials                        matlab.ui.control.EditField
        AdvancedtoolsTab              matlab.ui.container.Tab
        uipanel18_2                   matlab.ui.container.Panel
        UnneededifusingguvcviewLabel  matlab.ui.control.Label
        videoDuringStim               matlab.ui.control.CheckBox
        uipanel18                     matlab.ui.container.Panel
        mseqButton                    matlab.ui.control.Button
        mseqChange                    matlab.ui.control.EditField
        mseqStart                     matlab.ui.control.EditField
        useCumsum                     matlab.ui.control.CheckBox
        uipanel22                     matlab.ui.container.Panel
        applyWhiteNoiseBtn            matlab.ui.control.Button
        gaussianWhiteNoiseVariance    matlab.ui.control.EditField
        text35                        matlab.ui.control.Label
        uipanel23                     matlab.ui.container.Panel
        generateXYbutton              matlab.ui.control.Button
        uitable1                      matlab.ui.control.Table
        uipanel16                     matlab.ui.container.Panel
        impulse                       matlab.ui.control.CheckBox
        stimSettings                  matlab.ui.control.Button
        editLayerName                 matlab.ui.control.Button
        layerList                     matlab.ui.control.DropDown
        openLayerManager              matlab.ui.control.Button
        layerName                     matlab.ui.control.Label
        editName                      matlab.ui.control.Button
        stimName                      matlab.ui.control.Label
        text17                        matlab.ui.control.Label
        resetMissed                   matlab.ui.control.Button
        info                          matlab.ui.control.Label
        uipanel13                     matlab.ui.container.Panel
        text36                        matlab.ui.control.Label
        applyBgColour                 matlab.ui.control.Button
        editBgColor                   matlab.ui.control.EditField
        stimList                      matlab.ui.control.DropDown
        clear                         matlab.ui.control.Button
        grid                          matlab.ui.control.Button
        killScreen                    matlab.ui.control.Button
        initScreen                    matlab.ui.control.Button
        run                           matlab.ui.control.Button
        next                          matlab.ui.control.Button
        previous                      matlab.ui.control.Button
    end

    methods (Access = private)   
        function gotoLayer(app, layerNO)
            navData    = getappdata(0, 'navData');
            index      = navData.activeStim;
            z          = navData.marker(index);
            
            if z ~= layerNO %dont change if press on current layer
            
                %update
                navData.marker(index) = layerNO;                 %update index
                setappdata(0, 'navData', navData);               %save navData
            
                updateTable(app);
            end
        end
        
        function gotoStim(app, stimNO)
            
            chstimuli  = getappdata(0, 'chstimuli');
            navData    = getappdata(0, 'navData');
            index      = navData.activeStim;
            
            %dont change if press on current stim or if last stim in list
            if index ~= stimNO && stimNO <= length(chstimuli)
                %update
                navData.activeStim = stimNO;                     %update index
                setappdata(0, 'navData', navData);               %save navData
            
                %If the next gui belongs to the same template class we only change
                %the content of the gui. If the template differs we need to open
                %(eval) a new gui.
                if strcmp(chstimuli(index).fileNameGui, chstimuli(stimNO).fileNameGui)
                    updateFigure(app);
                else
                    %switch gui
                    nextGui  = chstimuli(stimNO).fileNameGui;
            
                    delete(gcf)    %close current gui
                    eval(nextGui); %start new gui
                end
            end
        end
        
        function initBgColorEditBox(app)
                navData    = getappdata(0, 'navData');
                chstimuli  = getappdata(0, 'chstimuli');
                index    = navData.activeStim;
                if ~isfield(chstimuli(index), 'targetBgColor')
                    chstimuli(index).targetBgColor = 255;
                    setappdata(0, 'chstimuli', chstimuli);
                end
                app.editBgColor.Value = num2str(chstimuli(index).targetBgColor);
        end
        
        function rowInterp(~, Row, Mode)
            
            %Interpolate from start to stop values linearly
            chstimuli = getappdata(0, 'chstimuli');
            navData   = getappdata(0, 'navData');
            
            index = navData.activeStim;
            z     = navData.marker(index);
            data  = chstimuli(index).layers(z).data;
            
            [R, C]  = size(data);
            
            if Row <= R && Row >= 1
            
                firstVal = data(Row, 1);
                lastVal  = data(Row, C);
            
                switch Mode
                    case 'LIN'
                        data(Row, 1:end) = linspace(firstVal, lastVal, C);
                    case 'LOG'
                        data(Row, 1:end) = logspace(log10(firstVal), log10(lastVal), C);
                    otherwise
                        disp(['Incorrect case(rowInterp: ' Mode ')']);
                end
            
                chstimuli(index).layers(z).data = data;
                setappdata(0, 'chstimuli', chstimuli);
            end
        end
        
        function rowShuffle(~, Row)
            
            %Interpolate from start to stop values linearly
            chstimuli = getappdata(0, 'chstimuli');
            navData   = getappdata(0, 'navData');
            
            index    = navData.activeStim;
            z        = navData.marker(index);
            
            data     = chstimuli(index).layers(z).data;
            
            [R, ~]   = size(data);
            
            if Row <= R && Row >= 1
            
                data(Row,:) = Shuffle(data(Row,:));
            
                chstimuli(index).layers(z).data = data;
                setappdata(0, 'chstimuli', chstimuli);
            end
        end
        
        function shiftColumn(app, step)
            % shift entire column by step
            
            chstimuli = getappdata(0, 'chstimuli');
            navData   = getappdata(0, 'navData');
            
            index    = navData.activeStim;
            z        = navData.marker(index);
            
            data     = chstimuli(index).layers(z).data;
            
            selectedCol = str2double(app.chosenCol.Value);
            
            moveToCol = min(max(selectedCol+step,1),C);
            
            if moveToCol~=selectedCol
            
                temp = data(:,moveToCol);
                data(:,moveToCol) = data(:,selectedCol);
                data(:,selectedCol) = temp;
            
                chstimuli(index).layers(z).data = data;
                setappdata(0, 'chstimuli', chstimuli);
            
                app.chosenCol.Value = num2str(moveToCol);
            
                updateFigure(app);
            
            end
        end
        
        function updateFigure(app)
            % --- Updates the figure
            
            navFcn(app, 'Update'); %Updates the next and run buttons
            updateTable(app);
            drawnow;
        end
        
        function updateTable(app)
            %------------------------------------------------------------------
            %--- Executes when the tableGui is updated
            
            navData     = getappdata(0, 'navData');  %Load navigation data
            screenData  = getappdata(0, 'screenData');  %Load navigation data
            chstimuli   = getappdata(0, 'chstimuli');
            
            %Read parameters to set (default values of chstimuli(index))
            index      = navData.activeStim;
            Z          = length(chstimuli(index).layers);
            z          = navData.marker(index);
            
            if z > Z
                z = Z;
                navData.marker(index) = Z; %if we lowered the number of objects
            end
            
            data  = chstimuli(index).layers(z).data;
            names = chstimuli(index).layers(z).parameters;
            
            [~, C] = size(data);
            
            % Check or uncheck impulse
            app.impulse.Value = chstimuli(index).layers(z).impulse;

            %Set the table data
            app.uitable1.Data = data;
            
            %Set row names
            app.uitable1.RowName = names;
            
            %Make table editable
            columnEditable  = true(1, C);
            app.uitable1.ColumnEditable = columnEditable;
            
            columnFormat = cell(1,C);
            columnFormat = cellfun(@(x) 'numeric', columnFormat, 'UniformOutput', false);
            app.uitable1.ColumnFormat = columnFormat;
            
            %Set columnnames and width
            columnName = cell(C, 1);
            columnWidth = cell(1, C);

            for c = 1:C
                columnName{c} = strcat('Trial_', num2str(c));
                columnWidth{c} = navData.colWidth;
            end

            app.uitable1.ColumnName = columnName;
            app.uitable1.ColumnWidth = columnWidth;
            
            %set numRuns
            app.numRuns.Value = num2str(C);
            
            %Title - Update the title so that it is correct.
            app.stimName.Text = chstimuli(index).name;
            
            %layername
            app.layerName.Text = ['Layer ' num2str(z) ':' chstimuli(index).layers(z).name];
            
            %Update project name
            app.figure1.Name = ['FlyFly 4.1: ' navData.fileName];
            
            %set info label
            if screenData.isInit
                newInfo{1} = ['Screen: ' num2str(screenData.partial(3)) 'x' ...
                    num2str(screenData.partial(4)) ' px, ' ...
                    num2str(screenData.hz) ' Hz'];
            else
                newInfo{1} = 'Screen: Not initialized';
            end
            
            newInfo{2} = ['Skipped frames: ' num2str(navData.skippedLatest)];
            newInfo{3} = ['Skipped total: '  num2str(navData.skippedFrames)];
            
            app.info.Text = newInfo;
            
            %set drop down list (stimuli)
            [~, C, ~] = size(chstimuli);
            stimOrder = cell(C, 1);
            for c = 1:C
                stimOrder{c} = [chstimuli(c).name];
                if c == navData.activeStim
                    stimOrder{c} = strcat(stimOrder{c}, '*');
                end
            end
            app.stimList.Items = stimOrder;
            app.stimList.ItemsData = 1:C;
            app.stimList.Value = index;
            
            
            %set drop down list (layer)
            [~, C, ~] = size(chstimuli(index).layers);
            layerOrder = cell(C, 1);
            
            for c = 1:C
                layerOrder{c} = [chstimuli(index).layers(c).name];
            
                if c == z
                    layerOrder{c} = strcat(layerOrder{c}, '*');
                end
            end
            app.layerList.Items = layerOrder;
            app.layerList.ItemsData = 1:C;
            app.layerList.Value = z;
            
            
            initBgColorEditBox(app);
            
            %disable "next" button
            if index == length(chstimuli)
                set(app.next, 'Visible', 'Off');
            else
                set(app.next, 'Visible', 'On');
            end
        end
        
        % Update components that require runtime configuration
        function addRuntimeConfigurations(app)
            
            % Set component properties that require runtime configuration
            app.uitable1.BackgroundColor = [1 1 1;0.9608 0.9608 0.9608];
            app.uitable1.ColumnFormat = {[] []};
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function tableGui_OpeningFcn(app, varargin)
            % --- Executes just before tableGui is made visible.
            
            % Add runtime required configuration - Added by Migration Tool
            addRuntimeConfigurations(app);
            
            % Ensure that the app appears on screen when run
            movegui(app.figure1, 'onscreen');

            % UIWAIT makes tableGui wait for user response (see UIRESUME)
            % uiwait(handles.figure1);
            
            navData   = getappdata(0, 'navData');
            
            screenRes = get(0, 'ScreenSize'); %[1 1 screenWidth screenHeight]
            
            set(gcf, 'Units' , 'Pixels');
            posdata = get(gcf,'Position');
            height = posdata(4);
            width = posdata(3);
            set(gcf, 'Position', [navData.windowPosition (screenRes(4)-height)/2 width height]);
            
            % Get path to flyfly
            flyflyPath = mfilename('fullpath');
            flyflyPath = erase(flyflyPath, 'tableGui');
            flyflyPath = flyflyPath + "Subfunctions";

            %enable subfunctions
            addpath(flyflyPath)

            app.previewVid.Visible = "on";
            app.previewVid.Enable = "off";
            
            %Save handle to this figure
            navFcn(app, 'AddHandle');
            
            %disp('tableGui started')
            updateFigure(app);
        end

        % Button pushed function: applyBgColour
        function applyBgColour_Callback(app, event)
            % --- Executes on button press in applyBgColour.
            
            navData    = getappdata(0, 'navData');
            chstimuli  = getappdata(0, 'chstimuli');
            index    = navData.activeStim;
            screenData = getappdata(0, 'screenData');
            
            Screen('FillRect', screenData.wPtr,  chstimuli(index).targetBgColor);
            Screen('FillRect', screenData.wPtr, screenData.triggerRGBoff, screenData.triggerPos); %trigger off
            Screen('Flip', screenData.wPtr);
            screenData.bgColor = chstimuli(index).targetBgColor;
            setappdata(0, 'screenData', screenData);
        end

        % Button pushed function: applyWhiteNoiseBtn
        function applyWhiteNoiseBtn_Callback(app, event)
            % --- Executes on button press in applyWhiteNoiseBtn.
                chstimuli = getappdata(0, 'chstimuli');
                navData   = getappdata(0, 'navData');
            
                index    = navData.activeStim;
                z        = navData.marker(index);
            
                data     = chstimuli(index).layers(z).data;
                [~, C] = size(data);
            
                row = str2double(app.chosenRow.value);
            
                variance = str2double(app.gaussianWhiteNoiseVariance.Value);
                noise_vector = pseudo_normal(0, variance, C);
            
                data(row, 1:end) = data(row, 1:end) + noise_vector;
            
                chstimuli(index).layers(z).data = data;
                setappdata(0, 'chstimuli', chstimuli);
            
                updateFigure(app);
        end

        % Menu selected function: avimovie
        function avimovie_Callback(app, event)
            % --------------------------------------------------------------------
            navData    = getappdata(0, 'navData');
            screenData = getappdata(0, 'screenData');
            chstimuli  = getappdata(0, 'chstimuli');
            
            index      = navData.activeStim;
            
            if screenData.isInit
            
                [~, C]  = size(chstimuli(index).layers(1).data); %same number of trials in all layers
            
                if app.trialSubset.Value %cut down data set
                    trialChoice = app.trials.Value;
                    if isempty(trialChoice)
                        trialChoice = 1:C;
                    else
                        trialChoice = eval(strrep(trialChoice,'end',num2str(C)));
                        % automatically clip values larger than the number of trials
                        trialChoice = min(C,trialChoice);
                    end
                else
                    trialChoice = 1:C;
                end
            
                fps = inputdlg({'Framerate to record in:'}, 'FPS', 1, {num2str(screenData.hz)});
                fps = str2double(fps{1});
            
                fprintf('Creating AVI file...\n');
                imageArray = getStimImages(chstimuli(index), screenData, navData, trialChoice, fps);
            
                [filename, pathname] = uiputfile('*.avi', 'Save stimulus movie to...');
                if isequal(filename,0) || isequal(pathname,0)
                    disp('File NOT saved!');
                else
                    disp('Working...');
                    fn = fullfile(pathname, filename);
                    aviobj = VideoWriter(fn);
                    aviobj.FrameRate = fps;
                    % Open the object so we can save the video
                    open(aviobj);
                    for k = 1:length(imageArray)
                        currframe = im2frame(imageArray{k});
                        writeVideo(aviobj,currframe);
                    end
                    close(aviobj);
                    fprintf('AVI file saved to %s!\n',fn);
                end
            else
                disp('Screen is not initialized');
            end
        end

        % Button pushed function: clear
        function clear_Callback(app, event)
            screenFcn('Clear');
            
            screenData = getappdata(0, 'screenData');
            screenData.inUse = 0;
            setappdata(0, 'screenData', screenData);
            updateFigure(app);
        end

        % Value changed function: editBgColor
        function editBgColor_Callback(app, event)
            % Hints: get(hObject,'String') returns contents of editBgColor as text
            %        str2double(get(hObject,'String')) returns contents of editBgColor as a double
            %disp('Edit: this is the callback');
            tbgc = str2double(app.editBgColor.Value);
            
            navData    = getappdata(0, 'navData');
            chstimuli  = getappdata(0, 'chstimuli');
            index    = navData.activeStim;
            % chstimuli(index).beforeBgColor = chstimuli(index).bgColor;
            chstimuli(index).targetBgColor = tbgc;
            setappdata(0, 'chstimuli', chstimuli);
        end

        % Button pushed function: editLayerName
        function editLayerName_Callback(app, event)
            % --- Executes on button press in editLayerName.
            chstimuli = getappdata(0, 'chstimuli');
            navData   = getappdata(0, 'navData');
            index    = navData.activeStim;
            
            newName = inputdlg({'Enter new layer name: '}, '', 1, {chstimuli(index).layers(navData.marker(index)).name});
            
            if ~isempty(newName)
            
                chstimuli(index).layers(navData.marker(index)).name = newName{1};
                setappdata(0, 'chstimuli', chstimuli);
            
                updateTable(app);
            end
        end

        % Button pushed function: editName
        function editName_Callback(app, event)
            % --- Executes on button press in editName.
            chstimuli = getappdata(0, 'chstimuli');
            navData   = getappdata(0, 'navData');
            index     = navData.activeStim;
            
            newName = inputdlg({'Enter new name: '}, '', 1, {chstimuli(index).name});
            
            if ~isempty(newName)
                chstimuli(index).name = newName{1};
                setappdata(0, 'chstimuli', chstimuli);
            
                updateTable(app);
            end
        end

        % Menu selected function: exit
        function exit_Callback(app, event)
            % --------------------------------------------------------------------
            close(gcf);
        end

        % Close request function: figure1
        function figure1_CloseRequestFcn(app, event)
            % --- Executes when user attempts to close figure1.
            % Hint: delete(hObject) closes the figure
            
            navFcn(app, 'Close'); % clean up
            delete(app.figure1);
        end

        % Button pushed function: generateXYbutton
        function generateXYbutton_Callback(app, event)
            % --- Executes on button press in generateXYbutton.
            	screendata = getappdata(0, 'screenData');
                if screendata.ifi == 0
                    warndlg('Please initialize screen first.');
                else
                    chstimuli = getappdata(0, 'chstimuli');
                    navData   = getappdata(0, 'navData');
                    index    = navData.activeStim;
                    z        = navData.marker(index);
                    data     = chstimuli(index).layers(z).data;
            
                    NUM_TRIALS = size(data, 2);
                    SUBWIDTH = data(1, 1);
                    SUBHEIGHT = data(2, 1);
                    MIDX = data(3, 1);
                    MIDY = data(4, 1);
                    SPEED = data(9, 1);
                    MOVE_DURATION = data(10,1)*screendata.ifi;
                    TARGET_SIZE = 15;
            
                    [data(5, :), data(6, :), data(7, :), data(8, :)] = ...
                        generateXY(NUM_TRIALS, SPEED, MOVE_DURATION, SUBWIDTH, SUBHEIGHT, MIDX, MIDY, TARGET_SIZE);
            
                    chstimuli(index).layers(z).data = data;
                    setappdata(0, 'chstimuli', chstimuli);
            
                    updateFigure(app);
                end
        end

        % Button pushed function: grid
        function grid_Callback(app, event)
            % --- Executes on button press in grid.
            screenData = getappdata(0, 'screenData');
            
            if screenData.isInit
                screenFcn('DrawGrid');
                Screen(screenData.wPtr, 'Flip');
            
                screenData.inUse = 0;
                setappdata(0, 'screenData', screenData);
                updateFigure(app);
            end
        end

        % Menu selected function: importStim
        function importStim_Callback(app, event)
            % --------------------------------------------------------------------
            menuFcn('importStim');
            updateFigure(app);
        end

        % Value changed function: impulse
        function impulse_Callback(app, event)
            % --- Executes on button press in impulse.
            % Hint: get(hObject,'Value') returns toggle state of impulse
            
            chstimuli = getappdata(0, 'chstimuli');
            navData   = getappdata(0, 'navData');
            index     = navData.activeStim;
            z         = navData.marker(index);
            
            chstimuli(index).layers(z).impulse = event.Value;
            
            setappdata(0, 'chstimuli', chstimuli);
        end

        % Menu selected function: imsequence
        function imsequence_Callback(app, event)
            % --------------------------------------------------------------------
            navData    = getappdata(0, 'navData');
            screenData = getappdata(0, 'screenData');
            chstimuli  = getappdata(0, 'chstimuli');
            
            index      = navData.activeStim;
            
            if screenData.isInit
            
                [~, C]  = size(chstimuli(index).layers(1).data); %same number of trials in all layers
            
                if app.trialSubset.Value %cut down data set
                    trialChoice = app.trials.Value;
            
                    if isempty(trialChoice)
                        trialChoice = 1:C;
                    else
                        trialChoice = eval(strrep(trialChoice,'end',num2str(C)));
                        % automatically clip values larger than the number of trials
                        trialChoice = min(C,trialChoice);
                    end
            
                else
                    trialChoice = 1:C;
                end
            
                fps = inputdlg({'Framerate to record in:'}, 'FPS', 1, {num2str(screenData.hz)});
                fps = str2double(fps{1});
                disp('Creating image sequence...')
                imageArray = getStimImages(chstimuli(index), screenData, navData, trialChoice, fps);
            
                name = inputdlg({'Name sequence '}, 'Name', 1, {chstimuli(navData.activeStim).name});
                if ~isempty(name) %empty if press on cancel
                    dir_name = uigetdir(cd,'Set target directory');
                    if ischar(dir_name)
                        ID    = char(datetime);
                        ID    = regexprep(ID, ':', '_'); %exchange ':' with '_'
                        for k = 1:length(imageArray)
                            imwrite(imageArray{k}, fullfile(dir_name,sprintf('%s %s_%04.f.png',name{1},ID,k)));
                        end
                        fprintf('Sequence saved to %s!\n',dir_name);
                    else
                        disp('Image sequence NOT saved!');
                    end
                else
                    disp('Image sequence NOT saved!');
                end
            else
                disp('Screen is not initialized');
            end
        end

        % Button pushed function: initScreen
        function initScreen_Callback(app, event)
            % --- Executes on button press in initScreen.
            screenData = getappdata(0, 'screenData');
            
            screenFcn('Init');
            app.previewVid.Enable = 'on';
            app.killScreen.Enable = 'on';
            app.initScreen.Enable = 'off';

            if screenData.useGuvcview && screenData.recording
                path = which('initCam.sh');
                command = "bash " + path + " &"; % links to bash script that starts guvcview with video parameters 
                disp('Trying to Init Cam');
                [~, ~] = system(command, '-echo'); % Runs command in terminal
            end

            updateFigure(app);
        end

        % Button pushed function: killScreen
        function killScreen_Callback(app, event)
            % --- Executes on button press in killScreen.
            screenData = getappdata(0, 'screenData');
            
            screenFcn('Kill');
            app.previewVid.Enable = 'off';
            app.killScreen.Enable = 'off';
            app.initScreen.Enable = 'on';

            if screenData.useGuvcview && screenData.recording
                command = "pkill guvcview"; % links to bash script that starts guvcview with video parameters 
                disp('Trying to close guvcview');
                [~, ~] = system(command, '-echo'); % Runs command in terminal
            end

            updateFigure(app);
        end

        % Value changed function: layerList
        function layerList_Callback(app, event)
            % --- Executes on selection change in layerList.
            % Hints: contents = cellstr(get(hObject,'String')) returns layerList contents as cell array
            %        contents{get(hObject,'Value')} returns selected item from layerList
            
            %get new selection
            layer = event.Value; %index of chosen stim
            gotoLayer(app, layer)
        end

        % Button pushed function: openLayerManager
        function openLayerManager_Callback(app, event)
            % --- Executes on button press in layerManager.
            layerManager(@app.updateTable); 
        end

        % Button pushed function: mseqButton
        function mseqButton_Callback(app, event)
            % --- Executes on button press in mseq.
            param = inputdlg({'Base value: ','N:', 'Shift:', 'Which sequence:'}, 'm-sequence', 1, {'2','6','1','1'});
            if ~isempty(param)
                baseVal = str2double(param{1});
                p = str2double(param{2});
                shift = str2double(param{3});
                which = str2double(param{4});
                if isnumeric(baseVal) && isnumeric(p) && isnumeric(shift) && isnumeric(which)
                    change = str2double(aapp.mseqChange.Value);
                    sequence = change*mseq(baseVal,p,shift,which);
            
                    chstimuli = getappdata(0, 'chstimuli');
                    navData   = getappdata(0, 'navData');
            
                    index    = navData.activeStim;
                    z        = navData.marker(index);
            
                    data     = chstimuli(index).layers(z).data;
                    chstimuli(index).layers(z).name = ...
                        sprintf('%s (mseq: %d,%d,%d,%d)',chstimuli(index).layers(z).name,baseVal,p,shift,which);
            
                    [~, C]  = size(data);
                    row = str2double(app.chosenRow.Value);
            
                    start = str2double(app.mseqStart.Value);
                    % extend sequence if it is too short
                    sequence = repmat(sequence,ceil(C/length(sequence)),1);
                    if get(app.useCumsum.Value)
                        data(row, 1:end) = start+cumsum(sequence(1:C));
                    else
                        data(row, 1:end) = start+sequence(1:C);
                    end
            
                    chstimuli(index).layers(z).data = data;
                    setappdata(0, 'chstimuli', chstimuli);
            
                    updateFigure(app);
                end
            end
        end

        % Menu selected function: new
        function new_Callback(app, event)
            % --------------------------------------------------------------------
            menuFcn('New');
        end

        % Button pushed function: next
        function next_Callback(app, event)
            % --- Executes on button press in next.
            %load data
            navData   = getappdata(0, 'navData');
            index     = navData.activeStim;                         %current stim
            
            gotoStim(app, index+1);
        end

        % Button pushed function: numRunsOk
        function numRunsOk_Callback(app, event)
            % --- Executes on button press in numRunsOk.
            chstimuli = getappdata(0, 'chstimuli');
            navData   = getappdata(0, 'navData');
            
            index    = navData.activeStim;
            userRuns = abs(str2double(app.numRuns.Value)); 
            
            if isempty(userRuns)
                userRuns = 1;
            end
            
            numLayers = length(chstimuli(index).layers);
            
            for z = 1:numLayers
            
                data     = chstimuli(index).layers(z).data;
                layerSettings = chstimuli(index).layers(z).settings;
                [~, C]   = size(data);
            
                if userRuns > C %add more columns
                    for c = 1:(userRuns-C)
                        data(:, C+c)     = data(:, C);
                        layerSettings(:, C+c) = layerSettings(:, C);
                    end
                elseif userRuns < C && userRuns > 0 %remove columns
                    for c = 1:(C-userRuns)
                        data(:, C-c+1)     = [];
                        layerSettings(:, C-c+1) = [];
                    end
                end
            
                chstimuli(index).layers(z).data     = data;
                chstimuli(index).layers(z).settings = layerSettings;
            end
            
            setappdata(0, 'chstimuli', chstimuli);
            updateTable(app);
        end

        % Menu selected function: openFile
        function openFile_Callback(app, event)
            % --------------------------------------------------------------------
            menuFcn('Open');
            updateFigure(app);
        end

        % Button pushed function: previous
        function previous_Callback(app, event)
            % --- Executes on button press in previous.
            navData   = getappdata(0, 'navData');    %navigation data
            index     = navData.activeStim;          %index of current gui
            
            gotoStim(app, index - 1)
        end

        % Button pushed function: resetMissed
        function resetMissed_Callback(app, event)
            % --- Executes on button press in resetMissed.
            navData = getappdata(0, 'navData');
            
            navData.skippedFrames = 0;
            
            disp('Total number of skipped frames reset');
            
            setappdata(0, 'navData', navData);
            updateTable(app);
        end

        % Button pushed function: rowLin
        function rowLin_Callback(app, event)
            % --- Executes on button press in rowLin.
            % Interpolate from start to stop values linearly
            if app.allRows.Value
            
                chstimuli = getappdata(0, 'chstimuli');
                navData   = getappdata(0, 'navData');
                index     = navData.activeStim;
                z         = navData.marker(index);
            
                data      = chstimuli(index).layers(z).data;
                [R, ~, ~] = size(data);
            
                for r = 1:R
                    rowInterp(app, r, 'LIN');
                end
            else
                row = str2double(app.chosenRow.Value);
                rowInterp(app, row, 'LIN');
            end
            updateFigure(app);
        end

        % Button pushed function: rowLog
        function rowLog_Callback(app, event)
            % --- Executes on button press in rowLog.
            %Interpolate from start to stop values logarithmicly
            if app.allRowsValue
            
                chstimuli = getappdata(0, 'chstimuli');
                navData   = getappdata(0, 'navData');
                index     = navData.activeStim;
                z         = navData.marker(index);
            
                data      = chstimuli(index).layers(z).data;
                [R, ~, ~] = size(data);
            
                for r = 1:R
                    rowInterp(app, r, 'LOG');
                end
            else
                row = str2double(app.chosenRowValue);
                rowInterp(app, row, 'LOG');
            end
            updateFigure(app);
        end

        % Button pushed function: rowShuffle_1
        function rowShuffle_Callback(app, event)
            % --- Executes on button press in rowShuffle.
            if app.allRowsValue
            
                chstimuli = getappdata(0, 'chstimuli');
                navData   = getappdata(0, 'navData');
                index     = navData.activeStim;
                z         = navData.marker(index);
            
                data      = chstimuli(index).layers(z).data;
                [R, ~, ~] = size(data);
            
                for r = 1:R
                    rowShuffle(app, r);
                end
            else
                row = str2double(app.chosenRowValue);
                rowShuffle(app, row);
            end
            updateFigure(app);
        end

        % Button pushed function: run
        function run_Callback(app, event)
            % --- Executes on button press in run.
            navData    = getappdata(0, 'navData');
            screenData = getappdata(0, 'screenData');
            chstimuli  = getappdata(0, 'chstimuli');
            
            index      = navData.activeStim;
            
            screenData.beforeBgColor = screenData.bgColor;
            
            if ~screenData.isInit %if screen is not init, initialize and close after run
                disp(' ');
                disp('Initilizing screen for single experiment...')
                screenFcn('Init');
                screenData  = getappdata(0, 'screenData');
                closeScreen = 1;
            else
                closeScreen = 0;
            end
            
            %Screen is now in use, update navData and figure
            screenData.inUse = 1;
            
            setappdata(0, 'screenData', screenData);
            
            updateFigure(app);
            
            [~, C]  = size(chstimuli(index).layers(1).data); %same number of trials in all layers
            
            if app.trialSubset.Value %cut down data set
                trialChoice = app.trials.Value;
            
                if isempty(trialChoice)
                    trialChoice = 1:C;
                else
                    trialChoice = eval(strrep(trialChoice,'end',num2str(C)));
                    % automatically clip values larger than the number of trials
                    trialChoice = min(C,trialChoice);
                end
            
            else
                trialChoice = 1:C;
            end
            
            %
            % --RUN STIMULUS HERE--
            navData    = getappdata(0, 'navData');
            chstimuli  = getappdata(0, 'chstimuli');
            index    = navData.activeStim;
            
            % chstimuli(index).beforeBgColor = chstimuli(index).bgColor;
            % chstimuli(index).bgColor = chstimuli(index).targetBgColor;
            % setappdata(0, 'chstimuli', chstimuli);
            
            screenData = getappdata(0, 'screenData');
            screenData.bgColor = chstimuli(index).targetBgColor;

            usePreview = app.videoDuringStim.Value;
            
            missed = animationLoop(chstimuli(index), screenData, navData, trialChoice, usePreview);

            navData.skippedLatest = missed;
            navData.skippedFrames = navData.skippedFrames + missed;
            
            % screenData.beforeBgColor = screenData.bgColor;
            screenData.inUse   = 0;
            
            setappdata(0, 'screenData', screenData);
            setappdata(0, 'navData',    navData);
            
            updateFigure(app);
            
            if closeScreen
                screenFcn('Kill');
            end
        end

        % Menu selected function: saveAs
        function saveAs_Callback(app, event)
            % --------------------------------------------------------------------
            menuFcn('SaveAs');
            updateTable(app);
        end

        % Menu selected function: settings
        function settings_Callback(app, event)
            % --------------------------------------------------------------------
            mainSettings;
        end

        % Button pushed function: shiftColLeft
        function shiftColLeft_Callback(app, event)
            % --- Executes on button press in shiftColLeft.
            shiftColumn(app, -1);
        end

        % Button pushed function: shiftColRight
        function shiftColRight_Callback(app, event)
            % --- Executes on button press in shiftColRight.
            shiftColumn(app, 1);
        end

        % Value changed function: stimList
        function stimList_Callback(app, event)
            % --- Executes on selection change in stimList.
            % Hints: contents = cellstr(get(hObject,'String')) returns stimList contents as cell array
            %        contents{get(hObject,'Value')} returns selected item from stimList
            
            %get new selection
            stim = event.Value; %index of chosen stim
            gotoStim(app, stim)
        end

        % Button pushed function: stimSettings
        function stimSettings_Callback(app, event)
            % --- Executes on button press in stimSettings.
            layerSettings;
        end

        % Value changed function: trialSubset
        function trialSubset_Callback(app, event)
            % --- Executes on button press in trialSubset.
            % Hint: get(hObject,'Value') returns toggle state of trialSubset
            
            if event.Value % Use single trial
                app.trials.Enable = 'On';
            else
                app.trials.Enable = 'Off';
            end
        end

        % Cell edit callback: uitable1
        function uitable1_CellEditCallback(app, event)
            % --- Executes when entered data in editable cell(s) in uitable1.
            % eventdata  structure with the following fields (see UITABLE)
            %	Indices: chosenRow and column indices of the cell(s) edited
            %	PreviousData: previous data for the cell(s) edited
            %	EditData: string(s) entered by the user
            %	NewData: EditData or its converted form set on the Data property. Empty if Data was not changed
            %	Error: error string when failed to convert EditData to appropriate value for Data
            
            % Update edited data
            chstimuli = getappdata(0, 'chstimuli');
            navData   = getappdata(0, 'navData');
            
            index     = navData.activeStim;
            z         = navData.marker(index);
            
            newData   = app.uitable1.Data;
            chstimuli(index).layers(z).data = newData;
            setappdata(0, 'chstimuli', chstimuli);
        end

        % Cell selection callback: uitable1
        function uitable1_CellSelectionCallback(app, event)
            % --- Executes when selected cell(s) is changed in uitable1.
            index = event.Indices;

            app.chosenRow.Value =  num2str(index(1));
            app.chosenCol.Value = num2str(index(2));
        end

        % Callback function
        function uitable1_KeyPressFcn(app, event)
            % --- Executes on key press with focus on uitable1 and none of its controls.
            % Paste data into sequencer table
            if length(event.Modifier) == 1 && strcmp(event.Modifier{:},'control') && ...
                strcmp(event.Key,'v')
                import = importdata('-pastespecial');
            
                chstimuli = getappdata(0, 'chstimuli');
                navData   = getappdata(0, 'navData');
            
                index    = navData.activeStim;
                z        = navData.marker(index);
            
                data     = chstimuli(index).layers(z).data;
            
                selectedRow = str2double(app.chosenRow.Value);
                selectedCol = str2double(app.chosenCol.Value);
            
                [r, c] = size(import);   % size of data in clipboard
                [R, C] = size(data);     % size of current data
            
                endRow = selectedRow+r-1;
                endRow = min(R,endRow);
            
                endCol = selectedCol+c-1;
                endCol = min(C,endCol);
            
                for i = selectedRow:endRow
                    for j = selectedCol:endCol
                        value = import(i-selectedRow+1, j-selectedCol+1);
                        if isnumeric(value)
                            data(i,j) = value;
                        end
                    end
                end
            
                chstimuli(index).layers(z).data = data;
                setappdata(0, 'chstimuli', chstimuli);
            
            end
            updateFigure(app);
        end

        % Button pushed function: useValue1
        function useValue1_Callback(app, event)
            % --- Executes on button press in useValue1.
            chstimuli = getappdata(0, 'chstimuli');
            navData   = getappdata(0, 'navData');
            
            row = str2double(app.chosenRow.Value);
            col = str2double(app.chosenCol.Value);
            
            numVal = str2double(app.valNum.Value);
            
            index    = navData.activeStim;
            z        = navData.marker(index);
            
            data     = chstimuli(index).layers(z).data;
            
            [~, C] = size(data);
            if app.allRows.Value
            
                for c = 1:C
                    if mod(c-col, numVal) == 0
                        data(:,c) = data(:,col);
                    end
                end
            
            else
                for c = 1:C
                    if mod(c-col, numVal) == 0
                        data(row,c) = data(row,col);
                    end
                end
            end
            
            chstimuli(index).layers(z).data  = data;
            setappdata(0, 'chstimuli', chstimuli);
            updateTable(app);
        end

        % Button pushed function: previewVid
        function previewVidButtonPushed(app, event)
            screenData = getappdata(0, 'screenData');
            if screenData.recording == 0
                warning("Please enable video recording to use this feature!")
                return
            end
            % Create video object
            if isfield(screenData, "webcamSettings")
                video = videoinput(screenData.videoAdaptor, 1, ...
                    screenData.webcamSettings.videoFormat);
            else
                video = videoinput(screenData.videoAdaptor, 1);
            end

            if isfield(screenData, "webcamSettings")
                % Apply camera specific settings (if available
                vidSrc = getselectedsource(video);
                wcSettings = screenData.webcamSettings;
                vidSrc.Brightness = wcSettings.Brightness;
                vidSrc.Contrast = wcSettings.Contrast;
                vidSrc.FrameRate = wcSettings.FrameRate;
                vidSrc.HorizontalFlip = wcSettings.HorizontalFlip;
                vidSrc.VerticalFlip = wcSettings.VerticalFlip;
                vidSrc.PowerLineFrequency = wcSettings.PowerLineFrequency;
                vidSrc.Saturation = wcSettings.Saturation;
                vidSrc.Sharpness = wcSettings.Sharpness;
                vidSrc.WhiteBalanceMode = wcSettings.WhiteBalanceMode;
            end 

            preview(video);
            pause('on')
            pause(5)
            pause('off')
            closepreview(video);
            delete(video);
        end

        % Value changed function: videoDuringStim
        function videoDuringStimValueChanged(app, event)
            value = app.videoDuringStim.Value;
            if value == 1
                msgbox("Warning: Previewing the video recording whilst stimuli is playing is known to cause frame drops", "Warning", "warn");
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.figure1 = uifigure('Visible', 'off');
            colormap(app.figure1, 'parula');
            app.figure1.Name = 'tableGui';
            app.figure1.CloseRequestFcn = createCallbackFcn(app, @figure1_CloseRequestFcn, true);
            app.figure1.Tag = 'figure1';
            app.file = uimenu(app.figure1);
            app.file.Text = 'File';
            app.file.Tag = 'file';
            app.new = uimenu(app.file);
            app.new.MenuSelectedFcn = createCallbackFcn(app, @new_Callback, true);
            app.new.Accelerator = 'N';
            app.new.Text = 'New';
            app.new.Tag = 'new';
            app.openFile = uimenu(app.file);
            app.openFile.MenuSelectedFcn = createCallbackFcn(app, @openFile_Callback, true);
            app.openFile.Accelerator = 'O';
            app.openFile.Text = 'Open';
            app.openFile.Tag = 'open';
            app.saveAs = uimenu(app.file);
            app.saveAs.MenuSelectedFcn = createCallbackFcn(app, @saveAs_Callback, true);
            app.saveAs.Accelerator = 'S';
            app.saveAs.Text = 'Save as...';
            app.saveAs.Tag = 'saveAs';
            app.importStim = uimenu(app.file);
            app.importStim.MenuSelectedFcn = createCallbackFcn(app, @importStim_Callback, true);
            app.importStim.Separator = 'on';
            app.importStim.Accelerator = 'I';
            app.importStim.Text = 'Import Stimulus';
            app.importStim.Tag = 'importStim';
            app.exit = uimenu(app.file);
            app.exit.MenuSelectedFcn = createCallbackFcn(app, @exit_Callback, true);
            app.exit.Separator = 'on';
            app.exit.Text = 'Exit';
            app.exit.Tag = 'exit';
            app.tools = uimenu(app.figure1);
            app.tools.Text = 'Tools';
            app.tools.Tag = 'tools';
            app.recordStimulus = uimenu(app.tools);
            app.recordStimulus.Accelerator = 'R';
            app.recordStimulus.Text = 'Record stimulus';
            app.recordStimulus.Tag = 'recordStimulus';
            app.avimovie = uimenu(app.recordStimulus);
            app.avimovie.MenuSelectedFcn = createCallbackFcn(app, @avimovie_Callback, true);
            app.avimovie.Text = 'AVI movie';
            app.avimovie.Tag = 'avimovie';
            app.imsequence = uimenu(app.recordStimulus);
            app.imsequence.MenuSelectedFcn = createCallbackFcn(app, @imsequence_Callback, true);
            app.imsequence.Text = 'Image sequence';
            app.imsequence.Tag = 'imsequence';
            app.settings = uimenu(app.tools);
            app.settings.MenuSelectedFcn = createCallbackFcn(app, @settings_Callback, true);
            app.settings.Separator = 'on';
            app.settings.Text = 'Settings';
            app.settings.Tag = 'settings';
            app.uipanel13 = uipanel(app.figure1);
            app.uipanel13.Tag = 'uipanel13';
            app.previous = uibutton(app.uipanel13, 'push');
            app.previous.ButtonPushedFcn = createCallbackFcn(app, @previous_Callback, true);
            app.previous.Tag = 'previous';
            app.previous.Text = 'Previous';
            app.next = uibutton(app.uipanel13, 'push');
            app.next.ButtonPushedFcn = createCallbackFcn(app, @next_Callback, true);
            app.next.Tag = 'next';
            app.next.Text = 'Next';
            app.run = uibutton(app.uipanel13, 'push');
            app.run.ButtonPushedFcn = createCallbackFcn(app, @run_Callback, true);
            app.run.Tag = 'run';
            app.run.Text = 'RUN';
            app.initScreen = uibutton(app.uipanel13, 'push');
            app.initScreen.ButtonPushedFcn = createCallbackFcn(app, @initScreen_Callback, true);
            app.initScreen.Tag = 'initScreen';
            app.initScreen.Text = 'Init Screen';
            app.killScreen = uibutton(app.uipanel13, 'push');
            app.killScreen.ButtonPushedFcn = createCallbackFcn(app, @killScreen_Callback, true);
            app.killScreen.Tag = 'killScreen';
            app.killScreen.Enable = 'off';
            app.killScreen.Text = 'Kill Screen';
            app.grid = uibutton(app.uipanel13, 'push');
            app.grid.ButtonPushedFcn = createCallbackFcn(app, @grid_Callback, true);
            app.grid.Tag = 'grid';
            app.grid.Text = 'Grid';
            app.clear = uibutton(app.uipanel13, 'push');
            app.clear.ButtonPushedFcn = createCallbackFcn(app, @clear_Callback, true);
            app.clear.Tag = 'clear';
            app.clear.Text = 'Clear';
            app.stimList = uidropdown(app.uipanel13);
            app.stimList.Items = {'Pop-up Menu'};
            app.stimList.ValueChangedFcn = createCallbackFcn(app, @stimList_Callback, true);
            app.stimList.Tag = 'stimList';
            app.stimList.Value = 'Pop-up Menu';
            app.editBgColor = uieditfield(app.uipanel13, 'text');
            app.editBgColor.ValueChangedFcn = createCallbackFcn(app, @editBgColor_Callback, true);
            app.editBgColor.Tag = 'editBgColor';
            app.editBgColor.Value = '100';
            app.applyBgColour = uibutton(app.uipanel13, 'push');
            app.applyBgColour.ButtonPushedFcn = createCallbackFcn(app, @applyBgColour_Callback, true);
            app.applyBgColour.Tag = 'applyBgColour';
            app.applyBgColour.Text = 'Apply';
            app.text36 = uilabel(app.uipanel13);
            app.text36.Tag = 'text36';
            app.text36.Text = 'Background Colour';
            app.uipanel16 = uipanel(app.figure1);
            app.uipanel16.Tag = 'uipanel16';
            app.info = uilabel(app.uipanel16);
            app.info.Tag = 'info';
            app.info.Text = {'Screen:'; 'Missed frames:'; 'Total:'};
            app.resetMissed = uibutton(app.uipanel16, 'push');
            app.resetMissed.ButtonPushedFcn = createCallbackFcn(app, @resetMissed_Callback, true);
            app.resetMissed.Tag = 'resetMissed';
            app.resetMissed.Text = 'Reset';
            app.text17 = uilabel(app.uipanel16);
            app.text17.Tag = 'text17';
            app.text17.Text = 'Info';
            app.stimName = uilabel(app.uipanel16);
            app.stimName.Tag = 'stimName';
            app.stimName.Text = 'tableGui';
            app.editName = uibutton(app.uipanel16, 'push');
            app.editName.ButtonPushedFcn = createCallbackFcn(app, @editName_Callback, true);
            app.editName.Tag = 'editName';
            app.editName.Text = 'Edit';
            app.layerName = uilabel(app.uipanel16);
            app.layerName.Tag = 'layerName';
            app.layerName.Text = 'subtitle';
            app.openLayerManager = uibutton(app.uipanel16, 'push');
            app.openLayerManager.ButtonPushedFcn = createCallbackFcn(app, @openLayerManager_Callback, true);
            app.openLayerManager.Tag = 'layerManager';
            app.openLayerManager.Text = 'Layer manager';
            app.layerList = uidropdown(app.uipanel16);
            app.layerList.Items = {'Pop-up Menu'};
            app.layerList.ValueChangedFcn = createCallbackFcn(app, @layerList_Callback, true);
            app.layerList.Tag = 'layerList';
            app.layerList.Value = 'Pop-up Menu';
            app.editLayerName = uibutton(app.uipanel16, 'push');
            app.editLayerName.ButtonPushedFcn = createCallbackFcn(app, @editLayerName_Callback, true);
            app.editLayerName.Tag = 'editLayerName';
            app.editLayerName.Text = 'Edit';
            app.stimSettings = uibutton(app.uipanel16, 'push');
            app.stimSettings.ButtonPushedFcn = createCallbackFcn(app, @stimSettings_Callback, true);
            app.stimSettings.Tag = 'stimSettings';
            app.stimSettings.Text = 'Layer settings';
            app.impulse = uicheckbox(app.uipanel16);
            app.impulse.ValueChangedFcn = createCallbackFcn(app, @impulse_Callback, true);
            app.impulse.Tag = 'impulse';
            app.impulse.Tooltip = 'If this is checked, movement will be calculated only for the first frame of each trial.';
            app.impulse.Text = 'Impulse';
            app.uitable1 = uitable(app.figure1);
            app.uitable1.ColumnName = {'Start'; 'Stop'};
            app.uitable1.ColumnRearrangeable = 'on';
            app.uitable1.RowName = {'Error'; ''};
            app.uitable1.ColumnEditable = [true true];
            app.uitable1.CellEditCallback = createCallbackFcn(app, @uitable1_CellEditCallback, true);
            app.uitable1.CellSelectionCallback = createCallbackFcn(app, @uitable1_CellSelectionCallback, true);
            app.uitable1.Tag = 'uitable1';
            app.TabGroup = uitabgroup(app.figure1);
            app.StandardtoolsTab = uitab(app.TabGroup);
            app.StandardtoolsTab.Title = 'Standard tools';
            app.uipanel1 = uipanel(app.StandardtoolsTab);
            app.uipanel1.Title = 'Sequencer';
            app.uipanel1.Tag = 'uipanel1';
            app.trials = uieditfield(app.uipanel1, 'text');
            app.trials.Tag = 'trials';
            app.trials.Enable = 'off';
            app.trials.Value = '1:end';
            app.trialSubset = uicheckbox(app.uipanel1);
            app.trialSubset.ValueChangedFcn = createCallbackFcn(app, @trialSubset_Callback, true);
            app.trialSubset.Tag = 'trialSubset';
            app.trialSubset.Text = ' Trial Subset';
            app.numRunsOk = uibutton(app.uipanel1, 'push');
            app.numRunsOk.ButtonPushedFcn = createCallbackFcn(app, @numRunsOk_Callback, true);
            app.numRunsOk.Tag = 'numRunsOk';
            app.numRunsOk.Text = 'OK';
            app.numRuns = uieditfield(app.uipanel1, 'text');
            app.numRuns.Tag = 'numRuns';
            app.numRuns.Value = '2';
            app.text5 = uilabel(app.uipanel1);
            app.text5.Tag = 'text5';
            app.text5.Text = 'Number of Trials:';
            app.uipanel2 = uipanel(app.StandardtoolsTab);
            app.uipanel2.Title = 'Interpolate';
            app.uipanel2.Tag = 'uipanel2';
            app.allRows = uicheckbox(app.uipanel2);
            app.allRows.Tag = 'allRows';
            app.allRows.Text = 'All rows';
            app.text10 = uilabel(app.uipanel2);
            app.text10.Tag = 'text10';
            app.text10.Text = 'Row/Col';
            app.rowLin = uibutton(app.uipanel2, 'push');
            app.rowLin.ButtonPushedFcn = createCallbackFcn(app, @rowLin_Callback, true);
            app.rowLin.Tag = 'rowLin';
            app.rowLin.Text = 'Lin';
            app.shiftColRight = uibutton(app.uipanel2, 'push');
            app.shiftColRight.ButtonPushedFcn = createCallbackFcn(app, @shiftColRight_Callback, true);
            app.shiftColRight.Tag = 'shiftColRight';
            app.shiftColRight.Tooltip = 'Shift column to the right';
            app.shiftColRight.Text = '>>';
            app.shiftColLeft = uibutton(app.uipanel2, 'push');
            app.shiftColLeft.ButtonPushedFcn = createCallbackFcn(app, @shiftColLeft_Callback, true);
            app.shiftColLeft.Tag = 'shiftColLeft';
            app.shiftColLeft.Tooltip = 'Shift column to the left';
            app.shiftColLeft.Text = '<<';
            app.valNum = uieditfield(app.uipanel2, 'text');
            app.valNum.Tag = 'valNum';
            app.valNum.Value = '1';
            app.chosenCol = uieditfield(app.uipanel2, 'text');
            app.chosenCol.Tag = 'chosenCol';
            app.chosenCol.Value = '1';
            app.useValue1 = uibutton(app.uipanel2, 'push');
            app.useValue1.ButtonPushedFcn = createCallbackFcn(app, @useValue1_Callback, true);
            app.useValue1.Tag = 'useValue1';
            app.useValue1.Text = 'Use value';
            app.rowShuffle_1 = uibutton(app.uipanel2, 'push');
            app.rowShuffle_1.ButtonPushedFcn = createCallbackFcn(app, @rowShuffle_Callback, true);
            app.rowShuffle_1.Tag = 'rowShuffle_1';
            app.rowShuffle_1.Text = 'Shuffle';
            app.rowLog = uibutton(app.uipanel2, 'push');
            app.rowLog.ButtonPushedFcn = createCallbackFcn(app, @rowLog_Callback, true);
            app.rowLog.Tag = 'rowLog';
            app.rowLog.Text = 'Log';
            app.chosenRow = uieditfield(app.uipanel2, 'text');
            app.chosenRow.Tag = 'chosenRow';
            app.chosenRow.Value = '1';
            app.PreviewVideoPanel = uipanel(app.StandardtoolsTab);
            app.PreviewVideoPanel.Title = 'Preview Video';
            app.previewVid = uibutton(app.PreviewVideoPanel, 'push');
            app.previewVid.ButtonPushedFcn = createCallbackFcn(app, @previewVidButtonPushed, true);
            app.previewVid.Tag = 'next';
            app.previewVid.Enable = 'off';
            app.previewVid.Text = 'Preview Video';
            app.AdvancedtoolsTab = uitab(app.TabGroup);
            app.AdvancedtoolsTab.Title = 'Advanced tools';
            app.uipanel23 = uipanel(app.AdvancedtoolsTab);
            app.uipanel23.Title = 'Paloma Target';
            app.uipanel23.Tag = 'uipanel23';
            app.generateXYbutton = uibutton(app.uipanel23, 'push');
            app.generateXYbutton.ButtonPushedFcn = createCallbackFcn(app, @generateXYbutton_Callback, true);
            app.generateXYbutton.Tag = 'generateXYbutton';
            app.generateXYbutton.Text = 'Generate XY';
            app.uipanel22 = uipanel(app.AdvancedtoolsTab);
            app.uipanel22.Title = 'Gaussian White Noise';
            app.uipanel22.Tag = 'uipanel22';
            app.text35 = uilabel(app.uipanel22);
            app.text35.Tag = 'text35';
            app.text35.Text = 'Variance';
            app.gaussianWhiteNoiseVariance = uieditfield(app.uipanel22, 'text');
            app.gaussianWhiteNoiseVariance.Tag = 'gaussianWhiteNoiseVariance';
            app.gaussianWhiteNoiseVariance.Value = '0';
            app.applyWhiteNoiseBtn = uibutton(app.uipanel22, 'push');
            app.applyWhiteNoiseBtn.ButtonPushedFcn = createCallbackFcn(app, @applyWhiteNoiseBtn_Callback, true);
            app.applyWhiteNoiseBtn.Tag = 'applyWhiteNoiseBtn';
            app.applyWhiteNoiseBtn.Text = 'APPLY';
            app.uipanel18 = uipanel(app.AdvancedtoolsTab);
            app.uipanel18.Title = 'm-sequence';
            app.uipanel18.Tag = 'uipanel18';
            app.useCumsum = uicheckbox(app.uipanel18);
            app.useCumsum.Tag = 'useCumsum';
            app.useCumsum.Text = ' cumsum';
            app.mseqStart = uieditfield(app.uipanel18, 'text');
            app.mseqStart.Tag = 'mseqStart';
            app.mseqStart.Value = '100';
            app.mseqChange = uieditfield(app.uipanel18, 'text');
            app.mseqChange.Tag = 'mseqChange';
            app.mseqChange.Value = '10';
            app.mseqButton = uibutton(app.uipanel18, 'push');
            app.mseqButton.ButtonPushedFcn = createCallbackFcn(app, @mseqButton_Callback, true);
            app.mseqButton.Tag = 'mseq';
            app.mseqButton.Text = 'MSeq';
            app.uipanel18_2 = uipanel(app.AdvancedtoolsTab);
            app.uipanel18_2.Title = 'Enable video preview';
            app.uipanel18_2.Tag = 'uipanel18';
            app.videoDuringStim = uicheckbox(app.uipanel18_2);
            app.videoDuringStim.ValueChangedFcn = createCallbackFcn(app, @videoDuringStimValueChanged, true);
            app.videoDuringStim.Tag = 'useCumsum';
            app.videoDuringStim.Text = 'Preview video during stimuli';
            app.UnneededifusingguvcviewLabel = uilabel(app.uipanel18_2);
            app.UnneededifusingguvcviewLabel.Text = '*Unneeded if using guvcview';
            % Show the figure after all components are created
            app.figure1.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = tableGui(varargin)

            runningApp = getRunningApp(app);

            % Check for running singleton app
            if isempty(runningApp)

                % Create UIFigure and components
                createComponents(app)

                % Register the app with App Designer
                registerApp(app, app.figure1)

                % Execute the startup function
                runStartupFcn(app, @(app)tableGui_OpeningFcn(app, varargin{:}))
            else

                % Focus the running singleton app
                figure(runningApp.figure1)

                app = runningApp;
            end

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.figure1)
        end
    end
end